classdef serialTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        commStartPanel        matlab.ui.container.Panel          % Communic...
        LabelNumericEditField matlab.ui.control.Label            % Comm Port
        commPort              matlab.ui.control.NumericEditField % [0 Inf]
        startComm             matlab.ui.control.Button           % Start Comm
        findPorts             matlab.ui.control.Button           % Find Ports
        endComm               matlab.ui.control.Button           % End Comm
        output                matlab.ui.control.TextArea        
        sendA                 matlab.ui.control.Button           % sendA
    end

    
    properties (Access = public)
        obj1;
        dontEnd = 0;
        dataFile = fopen('Data_History.txt','a+');
    end
    
    properties (Access = private)
       received = '';
%        outputString;
    end
    
    methods (Access = public)
        
%         function receiveData(app)
%             
%             disp('Hello');
%             
% %             disp(fget1(app.obj1));
%             
% %             app.received = cellstr(fscanf(app.obj1));
% %             outputString = app.output.Value;
% %             app.output.Value = [app.received ; outputString];
% 
%         end
        
        function mycallbacktest(app, src, event)
          thisline = (fscanf(src));
          disp(thisline);
%             disp('hello');
        end

        function results = func(app)
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.commPort.Value = 4;
        end

        % startComm button pushed function
        function startCommButtonPushed(app)

            port = app.commPort.Value;
            portString = ['COM' num2str(port)];
            
            % Find a serial port object.
            app.obj1 = instrfind('Type', 'serial', 'Port', portString, 'Tag', '');
%             s = serial('COM4');

            % Create the serial port object if it does not exist
            % otherwise use the object that was found.
            if isempty(app.obj1)
                app.obj1 = serial(portString);
            else
                fclose(app.obj1);
                app.obj1 = app.obj1(1);
            end
            
            % Configure instrument object, obj1.
            set(app.obj1, 'Terminator', {13,13});
            % Configure instrument object, obj1.
            set(app.obj1, 'Timeout', 10.0);
            
%             set(app.obj1, 'BytesAvailableFcnMode', 'byte');
%             set(app.obj1, 'BytesAvailableFcnCount', 1);
%             set(app.obj1, 'BytesAvailableFcn', @receiveData);
%             set(s, 'BytesAvailableFcnMode', 'terminator');
%             src = app.obj1;
%             s.BytesAvailableFcn = @receiveData;
            
            anonf = @(src,event) (disp(fscanf(src)));
            set(app.obj1, 'BytesAvailableFcnMode', 'terminator');
            app.obj1.BytesAvailableFcn = {@app.mycallbacktest};
            
            % Connect to instrument object, obj1.
            if isvalid(app.obj1)
                fopen(app.obj1);
                outputString = app.output.Value;
                app.output.Value = ['Port Connected'; outputString];
            else
                outputString = app.output.Value;
                app.output.Value = ['Port not valid'; outputString];
            end
            
        end

        % findPorts button pushed function
        function findPortsButtonPushed(app)
            out = instrfind('Type', 'serial');
            str = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(out);
            outputString = app.output.Value;
            app.output.Value = [str; outputString];
        end

        % endComm button pushed function
        function endCommButtonPushed(app)
            fclose(app.obj1);
            delete(app.obj1);
            outputString = app.output.Value;
            app.output.Value = ['Comm Ended'; outputString];
        end

        % sendA button pushed function
        function sendAButtonPushed(app)
            fprintf(app.obj1, 'A');
            outputString = app.output.Value;
            
            app.received = cellstr(fscanf(app.obj1));
            app.output.Value = [app.received; 'A Sent'; outputString];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.commStartPanel = uipanel(app.UIFigure);
            app.commStartPanel.Title = 'Communication Intialization';
            app.commStartPanel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.commStartPanel);
            app.LabelNumericEditField.Text = 'Comm Port';
            app.commPort = uieditfield(app.commStartPanel, 'numeric');
            app.commPort.Limits = [0 Inf];
            app.startComm = uibutton(app.commStartPanel, 'push');
            app.startComm.ButtonPushedFcn = createCallbackFcn(app, @startCommButtonPushed);
            app.startComm.Text = 'Start Comm';
            app.findPorts = uibutton(app.commStartPanel, 'push');
            app.findPorts.ButtonPushedFcn = createCallbackFcn(app, @findPortsButtonPushed);
            app.findPorts.Text = 'Find Ports';
            app.endComm = uibutton(app.commStartPanel, 'push');
            app.endComm.ButtonPushedFcn = createCallbackFcn(app, @endCommButtonPushed);
            app.endComm.Text = 'End Comm';
            app.output = uitextarea(app.UIFigure);
            app.sendA = uibutton(app.UIFigure, 'push');
            app.sendA.ButtonPushedFcn = createCallbackFcn(app, @sendAButtonPushed);
            app.sendA.Text = 'sendA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = serialTest()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end