classdef serialTest2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure         % UI Figure
        startComm matlab.ui.control.Button % startComm
    end


    properties (Access = public)
        
    end

    methods (Access = public)
        
        function mycallback(src, event, fid)
          thisline = fgetl(src);
          fprintf(fid, '%s\n', thisline);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % startComm button pushed function
        function startCommButtonPushed(app)
            s = serial('COM4');
            set(s, 'BytesAvailableFcnMode', 'terminator');
            fid = fopen('TheLogFile.csv', 'wt');
            s.BytesAvailableFcn = @(src,event) mycallback(src, event, fid)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.startComm = uibutton(app.UIFigure, 'push');
            app.startComm.ButtonPushedFcn = createCallbackFcn(app, @startCommButtonPushed);
            app.startComm.Text = 'startComm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = serialTest2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end